classdef color < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        pnlUmbrales  matlab.ui.container.Panel
        numAzulInf   matlab.ui.control.NumericEditField
        numAzulSup   matlab.ui.control.NumericEditField
        numVerdeInf  matlab.ui.control.NumericEditField
        numVerdeSup  matlab.ui.control.NumericEditField
        numRedInf    matlab.ui.control.NumericEditField
        numRedSup    matlab.ui.control.NumericEditField
        lblAzul      matlab.ui.control.Label
        lblVerde     matlab.ui.control.Label
        lblRojo      matlab.ui.control.Label
        lblColor     matlab.ui.control.Label
        lblInferior  matlab.ui.control.Label
        lblSuperior  matlab.ui.control.Label
        btnComenzar  matlab.ui.control.Button
        btnUmbrales  matlab.ui.control.Button
        btnColor     matlab.ui.control.Button
        imgColor     matlab.ui.control.Image
        axsGrafica   matlab.ui.control.UIAxes
        axsVideo     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        umbrales % Description
        Cx
        Cy
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ButtonPushed(app, event)
            
        end

        % Callback function
        function Button2Pushed(app, event)
            
        end

        % Button pushed function: btnColor
        function btnColorButtonPushed(app, event)
            tipos = {'*.jpg;.*jpge;*.png'};
            [file,path] = uigetfile(tipos);
            app.imgColor.ImageSource = fullfile(path,file);
        end

        % Button pushed function: btnUmbrales
        function btnUmbralesButtonPushed(app, event)
            Img = imread(app.imgColor.ImageSource);
            R = Img(:,:,1);
            G = Img(:,:,2);
            B = Img(:,:,3);
            rojoSup = mean2(R) + std2(R);
            app.numRedSup.Value = rojoSup;
            rojoInf = mean2(R) - std2(R);
            app.numRedInf.Value = rojoInf;
            verdeSup = mean2(G) + std2(G);
            app.numVerdeSup.Value = verdeSup;
            verdeInf = mean2(G) - std2(G);
            app.numVerdeInf.Value = verdeInf;
            azulSup = mean2(B) + std2(B);
            app.numAzulSup.Value = azulSup;
            azulInf = mean2(B) - std2(B);
            app.numAzulInf.Value = azulInf;
            app.umbrales = [rojoSup rojoInf verdeSup verdeInf azulSup azulInf];
            
        end

        % Button pushed function: btnComenzar
        function btnComenzarButtonPushed(app, event)
            [file,path] = uigetfile({'*.mp4*','video (*.mp4*)'},'Abre una video');

            video = VideoReader(fullfile(path,file));
            
            N = video.NumFrames;
            
            for i=1:N
                imagen = read(video,i);
                image(imagen,'Parent',app.axsVideo);
                pause(1/video.FrameRate);
                
                C = deteccion_de_objetos(imagen,app.umbrales);
    
                if(~isempty(C))
                    C = round(C);
                    app.Cx = [app.Cx ; C(:,1)];
                    app.Cy = [app.Cy ; C(:,2)];
                end
            end

            X = app.Cx;
            Y = app.Cy;

            plot(app.axsGrafica,X,Y,'b*-');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axsVideo = uiaxes(app.UIFigure);
            app.axsVideo.XTick = [];
            app.axsVideo.YTick = [];
            app.axsGrafica = uiaxes(app.UIFigure);
            title(app.axsGrafica, 'Title')
            xlabel(app.axsGrafica, 'X')
            ylabel(app.axsGrafica, 'Y')
            zlabel(app.axsGrafica, 'Z')
            app.imgColor = uiimage(app.UIFigure);
            app.btnColor = uibutton(app.UIFigure, 'push');
            app.btnColor.ButtonPushedFcn = createCallbackFcn(app, @btnColorButtonPushed, true);
            app.btnColor.Text = 'Seleccionar color';
            app.btnUmbrales = uibutton(app.UIFigure, 'push');
            app.btnUmbrales.ButtonPushedFcn = createCallbackFcn(app, @btnUmbralesButtonPushed, true);
            app.btnUmbrales.Text = 'Calcular umbrales';
            app.btnComenzar = uibutton(app.UIFigure, 'push');
            app.btnComenzar.ButtonPushedFcn = createCallbackFcn(app, @btnComenzarButtonPushed, true);
            app.btnComenzar.Text = 'Comenzar';
            app.pnlUmbrales = uipanel(app.UIFigure);
            app.pnlUmbrales.Title = 'Umbrales';
            app.lblSuperior = uilabel(app.pnlUmbrales);
            app.lblSuperior.Text = 'Superior';
            app.lblInferior = uilabel(app.pnlUmbrales);
            app.lblInferior.Text = 'Inferior';
            app.lblColor = uilabel(app.pnlUmbrales);
            app.lblColor.Text = 'Color';
            app.lblRojo = uilabel(app.pnlUmbrales);
            app.lblRojo.Text = 'Rojo';
            app.lblVerde = uilabel(app.pnlUmbrales);
            app.lblVerde.Text = 'Verde';
            app.lblAzul = uilabel(app.pnlUmbrales);
            app.lblAzul.Text = 'Azul';
            app.numRedSup = uieditfield(app.pnlUmbrales, 'numeric');
            app.numRedSup.Editable = 'off';
            app.numRedInf = uieditfield(app.pnlUmbrales, 'numeric');
            app.numRedInf.Editable = 'off';
            app.numVerdeSup = uieditfield(app.pnlUmbrales, 'numeric');
            app.numVerdeInf = uieditfield(app.pnlUmbrales, 'numeric');
            app.numAzulSup = uieditfield(app.pnlUmbrales, 'numeric');
            app.numAzulInf = uieditfield(app.pnlUmbrales, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = color

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end